classdef MideDistanciasApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Toolbar                  matlab.ui.container.Toolbar
        PushTool                 matlab.ui.container.toolbar.PushTool
        DistanciaEditFieldLabel  matlab.ui.control.Label
        DistanciaEditField       matlab.ui.control.EditField
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        IsMeasuring = false; % Flag to check if the app is on measuring
        currentTimer
        videoInput = videoinput('winvideo',1); % Video input object to interact with the camera
        roi = [129, 22, 59, 137];
    end
    
    methods (Access = private)
        
        function setupMeasureTimer(app)
            if isempty(app.currentTimer)
                app.currentTimer = timer( ...
                    'StartDelay', 0, ...
                    'Period', 0.001, ...
                    'TasksToExecute', Inf, ...
                    'ExecutionMode', 'fixedRate' ...
                );
                app.currentTimer.TimerFcn = @app.measureCallback;
            end
            
            
            timerStatus = get(app.currentTimer, 'Running');
            if ~isequal(timerStatus ,'on')
                start(app.currentTimer);
            end
        end
        
        function stopcurrentTimer(app)
            timerStatus = get(app.currentTimer, 'Running');
            if isequal(timerStatus ,'on')
                stop(app.currentTimer);
            end
        end
        
        function measureCallback(app, ~, ~, ~)
            im = getsnapshot(app.videoInput); 
            
            im = im(:,:,1);
            
            
            imROI = im(app.roi(2):app.roi(2)+app.roi(4), app.roi(1):app.roi(1)+app.roi(3));


            [row, ~] = find(imROI <= 125);
            mY = mean(row);
            
            interp = interpolacion_sensor(load('medidas2.mat').medidas, 3);
            
            distance = calcula_distancia(interp, mY);
            
            app.DistanciaEditField.Value = string(distance);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath('./icons/'));
            
            resolution = app.videoInput.VideoResolution;
            xsize = resolution(1);
            ysize = resolution(2);
            
            hImage = image(app.UIAxes,zeros(xsize,ysize,3));
            pause(2);
            app.UIAxes.XLim = [0,ysize];
            app.UIAxes.YLim = [0,xsize];
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            pbaspect(app.UIAxes,[ysize,xsize,1])
            
            preview(app.videoInput, hImage);
            drawrectangle('Position',app.roi, 'Parent',app.UIAxes, 'InteractionsAllowed', 'none');
        end

        % Clicked callback: PushTool
        function PushToolClicked(app, event)
            if app.IsMeasuring
                app.PushTool.Icon = 'play-solid.png';
                app.IsMeasuring = false;
                app.stopcurrentTimer();
                
                
            else
                app.PushTool.Icon = 'stop-solid.png';
                app.IsMeasuring = true;
                app.setupMeasureTimer();
                
                
            end
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.IsMeasuring = false;
            if ~isempty(app.currentTimer)
                stop(app.currentTimer);
                delete(app.currentTimer);
            end
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.ClickedCallback = createCallbackFcn(app, @PushToolClicked, true);
            app.PushTool.Icon = 'play-solid.png';
            app.DistanciaEditFieldLabel = uilabel(app.UIFigure);
            app.DistanciaEditFieldLabel.Text = 'Distancia';
            app.DistanciaEditField = uieditfield(app.UIFigure, 'text');
            app.DistanciaEditField.Editable = 'off';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MideDistanciasApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end